classdef init_angles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        XAnklePitchEditField_2       matlab.ui.control.NumericEditField
        XAnklePitchEditField_2Label  matlab.ui.control.Label
        AnkleRollEditField_2         matlab.ui.control.NumericEditField
        AnkleRollEditField_2Label    matlab.ui.control.Label
        KneeRollEditField_2          matlab.ui.control.NumericEditField
        KneeRollEditField_2Label     matlab.ui.control.Label
        HipPitchEditField_2          matlab.ui.control.NumericEditField
        HipPitchEditField_2Label     matlab.ui.control.Label
        HipRollEditField_5           matlab.ui.control.NumericEditField
        HipRollEditField_5Label      matlab.ui.control.Label
        XAnklePitchEditField         matlab.ui.control.NumericEditField
        XAnklePitchEditFieldLabel    matlab.ui.control.Label
        AnkleRollEditField           matlab.ui.control.NumericEditField
        AnkleRollEditFieldLabel      matlab.ui.control.Label
        KneeRollEditField            matlab.ui.control.NumericEditField
        KneeRollEditFieldLabel       matlab.ui.control.Label
        HipPitchEditField            matlab.ui.control.NumericEditField
        HipPitchEditFieldLabel       matlab.ui.control.Label
        HipRollEditField             matlab.ui.control.NumericEditField
        HipRollEditFieldLabel        matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        TextArea                     matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HipRollEditField
        function HipRollEditFieldValueChanged(app, event)
            value = app.HipRollEditField.Value;
            assignin('base','Left_hip_roll',value);
        end

        % Value changed function: HipPitchEditField
        function HipPitchEditFieldValueChanged(app, event)
            value = app.HipPitchEditField.Value;
            assignin('base','Left_hip_pitch',value);
        end

        % Value changed function: KneeRollEditField
        function KneeRollEditFieldValueChanged(app, event)
            value = app.KneeRollEditField.Value;
            assignin('base','Left_knee_roll',value);
        end

        % Value changed function: AnkleRollEditField
        function AnkleRollEditFieldValueChanged(app, event)
            value = app.AnkleRollEditField.Value;
            assignin('base','Left_ankle_roll',value);
        end

        % Value changed function: XAnklePitchEditField
        function XAnklePitchEditFieldValueChanged(app, event)
            value = app.XAnklePitchEditField.Value;
            assignin('base','Left_ankle_pitch',value);
        end

        % Value changed function: HipRollEditField_5
        function HipRollEditField_5ValueChanged(app, event)
            value = app.HipRollEditField_5.Value;
            assignin('base','Right_hip_roll',value);
        end

        % Value changed function: HipPitchEditField_2
        function HipPitchEditField_2ValueChanged(app, event)
            value = app.HipPitchEditField_2.Value;
            assignin('base','Right_hip_pitch',value);
        end

        % Value changed function: KneeRollEditField_2
        function KneeRollEditField_2ValueChanged(app, event)
            value = app.KneeRollEditField_2.Value;
            assignin('base','Right_knee_roll',value);
        end

        % Value changed function: AnkleRollEditField_2
        function AnkleRollEditField_2ValueChanged(app, event)
            value = app.AnkleRollEditField_2.Value;
            assignin('base','Right_ankle_roll',value);
        end

        % Value changed function: XAnklePitchEditField_2
        function XAnklePitchEditField_2ValueChanged(app, event)
            value = app.XAnklePitchEditField_2.Value;
            assignin('base','Right_ankle_pitch',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'LEFT LEG'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'RIGHT LEG'};
            app.HipRollEditFieldLabel = uilabel(app.UIFigure);
            app.HipRollEditFieldLabel.Text = 'Hip Roll';
            app.HipRollEditField = uieditfield(app.UIFigure, 'numeric');
            app.HipRollEditField.ValueChangedFcn = createCallbackFcn(app, @HipRollEditFieldValueChanged, true);
            app.HipPitchEditFieldLabel = uilabel(app.UIFigure);
            app.HipPitchEditFieldLabel.Text = 'Hip Pitch';
            app.HipPitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.HipPitchEditField.ValueChangedFcn = createCallbackFcn(app, @HipPitchEditFieldValueChanged, true);
            app.KneeRollEditFieldLabel = uilabel(app.UIFigure);
            app.KneeRollEditFieldLabel.Text = 'Knee Roll';
            app.KneeRollEditField = uieditfield(app.UIFigure, 'numeric');
            app.KneeRollEditField.ValueChangedFcn = createCallbackFcn(app, @KneeRollEditFieldValueChanged, true);
            app.AnkleRollEditFieldLabel = uilabel(app.UIFigure);
            app.AnkleRollEditFieldLabel.Text = 'Ankle Roll';
            app.AnkleRollEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnkleRollEditField.ValueChangedFcn = createCallbackFcn(app, @AnkleRollEditFieldValueChanged, true);
            app.XAnklePitchEditFieldLabel = uilabel(app.UIFigure);
            app.XAnklePitchEditFieldLabel.Text = 'X Ankle Pitch';
            app.XAnklePitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.XAnklePitchEditField.ValueChangedFcn = createCallbackFcn(app, @XAnklePitchEditFieldValueChanged, true);
            app.HipRollEditField_5Label = uilabel(app.UIFigure);
            app.HipRollEditField_5Label.Text = 'Hip Roll';
            app.HipRollEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.HipRollEditField_5.ValueChangedFcn = createCallbackFcn(app, @HipRollEditField_5ValueChanged, true);
            app.HipPitchEditField_2Label = uilabel(app.UIFigure);
            app.HipPitchEditField_2Label.Text = 'Hip Pitch';
            app.HipPitchEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HipPitchEditField_2.ValueChangedFcn = createCallbackFcn(app, @HipPitchEditField_2ValueChanged, true);
            app.KneeRollEditField_2Label = uilabel(app.UIFigure);
            app.KneeRollEditField_2Label.Text = 'Knee Roll';
            app.KneeRollEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.KneeRollEditField_2.ValueChangedFcn = createCallbackFcn(app, @KneeRollEditField_2ValueChanged, true);
            app.AnkleRollEditField_2Label = uilabel(app.UIFigure);
            app.AnkleRollEditField_2Label.Text = 'Ankle Roll';
            app.AnkleRollEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AnkleRollEditField_2.ValueChangedFcn = createCallbackFcn(app, @AnkleRollEditField_2ValueChanged, true);
            app.XAnklePitchEditField_2Label = uilabel(app.UIFigure);
            app.XAnklePitchEditField_2Label.Text = 'X Ankle Pitch';
            app.XAnklePitchEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XAnklePitchEditField_2.ValueChangedFcn = createCallbackFcn(app, @XAnklePitchEditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = init_angles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end